classdef v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridSzieDropDownLabel       matlab.ui.control.Label
        GridSzieDropDown            matlab.ui.control.DropDown
        NumberofminesDropDownLabel  matlab.ui.control.Label
        NumberofminesDropDown       matlab.ui.control.DropDown
        SolutionButton              matlab.ui.control.Button
        FlagButton                  matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
    end


    methods (Access = private)
        
        function cmHandle = axesHoldCM
           cmHandle = uicontextmenu;
           uimenu(cmHandle,'Label','hold on','Callback',@holdOn);
           uimenu(cmHandle,'Label','hold off','Callback',@holdOff);
        end
    
        function results = btnfunc(app,ni,nj)
            global btn m mMax btn GameGrid GameGridText Nmines Flag;
            if strcmp(btn{ni,nj}.Enable,'on')
                if Flag==1
                    if strcmp(btn{ni,nj}.Text,'')
                        btn{ni,nj}.Text = '¶';
                    else
                        btn{ni,nj}.Text = '';
                    end
                    Flag=0;
                    app.FlagButton.BackgroundColor = [0.96 0.96 0.96];
                else
            app.SolutionButton.BackgroundColor = [0 1 0];
            btn{ni,nj}.Text = GameGridText{ni,nj};
            btn{ni,nj}.Enable = 'off';

            
            if strcmp(GameGridText{ni,nj},'')
%                 ClickNew = [ni-1:ni+1;nj-1:nj+1];
%                 for i=1:2
%                     if ClickNew(i,1) < 1
%                         ClickNew(i,1) = 1;
%                     elseif ClickNew(i,3) >= mMax
%                         ClickNew(i,3) = mMax;
%                     end
%                 end
                for i=-1:1
                    for j=-1:1
                        newi=ni+i; newj = nj+j;
                        if newi > 0 && newi <= mMax && newj>0 && newj<=mMax
                            app.SolutionButton.BackgroundColor = [0 1 1];
    %                         btnfunc(app,ClickNew(1,i),ClickNew(2,j));
                            btnfunc(app,newi,newj);
                        end
                    end
                end
                
            end % strcmp(GameGridText{ni,nj},'')
            
            %% Check winning condition
            check = 0;
            for i=1:m
                for j=1:m
                    if strcmp(btn{i,j}.Enable,'on') && GameGrid(i,j) == 0
                        check = check + 1;
                    end
                end
            end
            app.SolutionButton.Text = num2str(check);
            if check == 0
                app.UIFigure.Color = [0 1 0];
                for i=1:m
                    for j=1:m
                        btn{i,j}.Enable = 'off';
                    end
                end
            end
            % Fail condition
            if GameGrid(ni,nj)
                app.UIFigure.Color = [1 0 0];
                for i=1:m
                    for j=1:m
                        btn{i,j}.Enable = 'off';
                        btn{i,j}.Text = GameGridText{i,j};
                    end
                end
            end
            
            end % Flag ==1
            end % strcmp(btn{ni,nj}.Enable,'on')

        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%
        
        function results = GameInit(app)
            global mine m n mMax btn GameGrid GameGridText Nmines;
            
            for i=1:mMax
                for j=1:mMax
                    btn{i,j}.Visible = 'off';
                    btn{i,j}.Text = '';
                end
            end
            
            for i=1:m
                for j=1:m
                    btn{i,j}.Visible = 'on';
                end
            end
            
            for i=1:Nmines
                mine{i} = [randi(m) randi(n)];
            end
            
            GameGrid = zeros(mMax);
            for i=1:Nmines
                GameGrid(randi(m),randi(m)) = 1;
            end
            
            for i=1:mMax
                for j=1:mMax
                    if GameGrid(i,j)
                        GameGridText{i,j} = '*';
                    elseif i==1
                        if j==1
                            GameGridText{i,j} = num2str(sum(sum(GameGrid(i:i+1,j:j+1))));
                        elseif j==mMax
                            GameGridText{i,j} = num2str(sum(sum(GameGrid(i:i+1,j-1:j))));
                        else
                            GameGridText{i,j} = num2str(sum(sum(GameGrid(i:i+1,j-1:j+1))));
                        end
                    elseif i==mMax
                        if j==1
                            GameGridText{i,j} = num2str(sum(sum(GameGrid(i-1:i,j:j+1))));
                        elseif j==mMax
                            GameGridText{i,j} = num2str(sum(sum(GameGrid(i-1:i,j-1:j))));
                        else
                            GameGridText{i,j} = num2str(sum(sum(GameGrid(i-1:i,j-1:j+1))));
                        end
                    else
                        if j==1
                            GameGridText{i,j} = num2str(sum(sum(GameGrid(i-1:i+1,j:j+1))));
                        elseif j==mMax
                            GameGridText{i,j} = num2str(sum(sum(GameGrid(i-1:i+1,j-1:j))));
                        else
                            GameGridText{i,j} = num2str(sum(sum(GameGrid(i-1:i+1,j-1:j+1))));
                        end
                    end
                    if strcmp(GameGridText{i,j},'0')
                        GameGridText{i,j} = '';
                    end
                    btn{i,j}.Text = '';
                    btn{i,j}.Enable = 'on';
                end
            end
        end
        
        end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global mine m n mMax btn GameGrid GameGridText Nmines;
            m=8;
            Nmines = 15;
            mMax = 8;
            for i=1:mMax
                for j = 1:mMax
                    btn{i,j} = uibutton(app.UIFigure,'Text','','Position',[50+50*(i-1),380-50*(j-1),40,40],'Visible','off','FontSize',28,'ButtonPushedFcn', @(btn,event) btnfunc(app,i,j));
                end
            end
            
            GameInit(app);

            app.SolutionButton.BackgroundColor = [1 0 0];


        end

        % Value changed function: GridSzieDropDown
        function GridSzieDropDownValueChanged(app, event)
            value = app.GridSzieDropDown.Value;
            global m mMax btn GameGrid GameGridText Nmines;
            m = str2double(value(1));
            
            GameInit(app);


        end

        % Button pushed function: SolutionButton
        function SolutionButtonPushed(app, event)
             global m mMax btn GameGrid GameGridText Nmines;

             for i=1:m
                for j=1:m
                    btn{i,j}.Visible = 'on';
                    btn{i,j}.Text = GameGridText{i,j};
                end
            end
        end

        % Value changed function: NumberofminesDropDown
        function NumberofminesDropDownValueChanged(app, event)
            value = app.NumberofminesDropDown.Value;
            global m mMax btn GameGrid GameGridText Nmines;
            Nmines = str2double(value);
            GameInit(app);

        end

        % Button pushed function: FlagButton
        function FlagButtonPushed(app, event)
            global Flag;
            Flag = 1;
            app.FlagButton.BackgroundColor = [1 0 0];
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            v2
            delete(app.UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.GridSzieDropDownLabel = uilabel(app.UIFigure);
            app.GridSzieDropDownLabel.Text = 'Grid Szie';
            app.GridSzieDropDown = uidropdown(app.UIFigure);
            app.GridSzieDropDown.Items = {'4 x 4', '5 x 5', '6 x 6', '7 x 7', '8 x 8'};
            app.GridSzieDropDown.ValueChangedFcn = createCallbackFcn(app, @GridSzieDropDownValueChanged, true);
            app.GridSzieDropDown.Value = '8 x 8';
            app.NumberofminesDropDownLabel = uilabel(app.UIFigure);
            app.NumberofminesDropDownLabel.Text = 'Number of mines';
            app.NumberofminesDropDown = uidropdown(app.UIFigure);
            app.NumberofminesDropDown.Items = {'6', '8', '10', '15', '20'};
            app.NumberofminesDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofminesDropDownValueChanged, true);
            app.NumberofminesDropDown.Value = '15';
            app.SolutionButton = uibutton(app.UIFigure, 'push');
            app.SolutionButton.ButtonPushedFcn = createCallbackFcn(app, @SolutionButtonPushed, true);
            app.SolutionButton.Text = 'Solution';
            app.FlagButton = uibutton(app.UIFigure, 'push');
            app.FlagButton.ButtonPushedFcn = createCallbackFcn(app, @FlagButtonPushed, true);
            app.FlagButton.Text = 'Flag';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
        end
    end

    methods (Access = public)

        % Construct app
        function app = v2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end